classdef genPasswordGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        v101UIFigure    matlab.ui.Figure
        EditFieldPWzs   matlab.ui.control.NumericEditField
        Label_4         matlab.ui.control.Label
        Buttonexit      matlab.ui.control.Button
        Buttonsave      matlab.ui.control.Button
        Buttongen       matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        asdfQWELabel    matlab.ui.control.Label
        CheckBoxJCb     matlab.ui.control.CheckBox
        abcLabel        matlab.ui.control.Label
        CheckBoxJCa     matlab.ui.control.CheckBox
        EditFieldZF     matlab.ui.control.EditField
        CheckBoxZF      matlab.ui.control.CheckBox
        CheckBoxDX      matlab.ui.control.CheckBox
        CheckBoxXX      matlab.ui.control.CheckBox
        CheckBoxSZ      matlab.ui.control.CheckBox
        EditFieldPWlen  matlab.ui.control.NumericEditField
        Label_3         matlab.ui.control.Label
        LabelZZ         matlab.ui.control.Label
        LabelBT         matlab.ui.control.Label
        TextAreaJG      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        mpwstr = ''; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.v101UIFigure,'center');
        end

        % Button pushed function: Buttongen
        function ButtongenPushed(app, event)
            pwlen = app.EditFieldPWlen.Value;
            if ~isempty(pwlen)
                szval = double(app.CheckBoxSZ.Value);
                xxval = double(app.CheckBoxXX.Value);
                dxval = double(app.CheckBoxDX.Value);
                zfval = double(app.CheckBoxZF.Value);
                zfstr = app.EditFieldZF.Value;
                if zfval
                    if isempty(zfstr)
                        app.mpwstr = '';
                        app.TextAreaJG.Value = {''};
                        msgbox('选中符号类型时，必须提供相应的符号!','温馨提示','error');
                        return;
                    end
                end
                if any([szval,xxval,dxval,zfval])
                    pwnum = app.EditFieldPWzs.Value;
                    if ~isempty(pwnum)
                        
                        jcaval = double(app.CheckBoxJCa.Value);
                        jcbval = double(app.CheckBoxJCa.Value);
                        if pwnum == 1
                            pwstr = generatePasswordMex(pwlen, szval, xxval, dxval, zfval, jcaval, jcbval, zfstr);
                        else
                            pwstr = generatePasswordMex(pwlen, szval, xxval, dxval, zfval, jcaval, jcbval, zfstr);
                            for k = 2:pwnum
                                pwstr = [pwstr,newline,generatePasswordMex(pwlen, szval, xxval, dxval, zfval, jcaval, jcbval, zfstr)];
                            end
                        end
                        app.TextAreaJG.Value = pwstr;
                        app.mpwstr = pwstr;
                    else
                        app.mpwstr = '';
                        app.TextAreaJG.Value = {''};
                        msgbox('密码组数不能空!','温馨提示','error');
                        return;
                    end
                else
                    app.mpwstr = '';
                    app.TextAreaJG.Value = {''};
                    msgbox('请至少选择一种字符类型!','温馨提示','error');
                    return;
                end
            else
                app.mpwstr = '';
                app.TextAreaJG.Value = {''};
                msgbox('密码长度不能空!','温馨提示','error');
                return;
            end
        end

        % Value changed function: CheckBoxZF
        function CheckBoxZFValueChanged(app, event)
            val = app.CheckBoxZF.Value;
            switch(val)
                case 0
                    app.EditFieldZF.Enable = "off";
                case 1
                    app.EditFieldZF.Enable = "on";
            end
        end

        % Button pushed function: Buttonsave
        function ButtonsavePushed(app, event)

            if ~isempty(app.mpwstr)
                [fname,pname,fi]=uiputfile('*.txt','保存密码文件');
                if fi == 1
                    filename = [pname,fname];
                    fid = fopen(filename,'wt');
                    fprintf(fid,'%s\r','生成de密码:');
                    fprintf(fid,'%s\r','-------------------------------------------------------------------------------');
                    fprintf(fid,'%s\r',app.mpwstr);
                    fprintf(fid,'%s\r','-------------------------------------------------------------------------------');
                    fprintf(fid,'%s',['保存时间:',char(datetime('now'))]);
                    fclose(fid);
                    msgbox('保存密码文件成功！')
                else
                    msgbox('已取消保存密码文件！')
                end
            else
                msgbox('请先生成密码后再保存!','温馨提示','error');
                return;
            end
        end

        % Button pushed function: Buttonexit
        function ButtonexitPushed(app, event)
            delete(app.v101UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.v101UIFigure = uifigure('Visible', 'off');
            app.v101UIFigure.Name = '强密码生成器 v1.0.1  —— 由巴山学长制作';
            app.TextAreaJG = uitextarea(app.v101UIFigure);
            app.LabelBT = uilabel(app.v101UIFigure);
            app.LabelBT.Text = '强密码生成器';
            app.LabelZZ = uilabel(app.v101UIFigure);
            app.LabelZZ.Text = '@巴山学长';
            app.Label_3 = uilabel(app.v101UIFigure);
            app.Label_3.Text = '长度';
            app.EditFieldPWlen = uieditfield(app.v101UIFigure, 'numeric');
            app.EditFieldPWlen.Limits = [8 512];
            app.EditFieldPWlen.Value = 12;
            app.Panel = uipanel(app.v101UIFigure);
            app.CheckBoxSZ = uicheckbox(app.Panel);
            app.CheckBoxSZ.Text = '数字';
            app.CheckBoxSZ.Value = true;
            app.CheckBoxXX = uicheckbox(app.Panel);
            app.CheckBoxXX.Text = '小写';
            app.CheckBoxXX.Value = true;
            app.CheckBoxDX = uicheckbox(app.Panel);
            app.CheckBoxDX.Text = '大写';
            app.CheckBoxZF = uicheckbox(app.Panel);
            app.CheckBoxZF.ValueChangedFcn = createCallbackFcn(app, @CheckBoxZFValueChanged, true);
            app.CheckBoxZF.Text = '符号';
            app.EditFieldZF = uieditfield(app.Panel, 'text');
            app.EditFieldZF.Enable = 'off';
            app.EditFieldZF.Value = '!@#$%.&';
            app.CheckBoxJCa = uicheckbox(app.Panel);
            app.CheckBoxJCa.Text = '连续数字或字母';
            app.abcLabel = uilabel(app.Panel);
            app.abcLabel.Text = '注：检测是否存在3个及以上的连续数字或字母，如456，abc等';
            app.CheckBoxJCb = uicheckbox(app.Panel);
            app.CheckBoxJCb.Text = '连续键盘字母';
            app.asdfQWELabel = uilabel(app.Panel);
            app.asdfQWELabel.Text = '注：检测是否存在3个及以上的键盘字母，如asdf，QWE等';
            app.Buttongen = uibutton(app.v101UIFigure, 'push');
            app.Buttongen.ButtonPushedFcn = createCallbackFcn(app, @ButtongenPushed, true);
            app.Buttongen.Text = '生成';
            app.Buttonsave = uibutton(app.v101UIFigure, 'push');
            app.Buttonsave.ButtonPushedFcn = createCallbackFcn(app, @ButtonsavePushed, true);
            app.Buttonsave.Text = '保存';
            app.Buttonexit = uibutton(app.v101UIFigure, 'push');
            app.Buttonexit.ButtonPushedFcn = createCallbackFcn(app, @ButtonexitPushed, true);
            app.Buttonexit.Text = '退出';
            app.Label_4 = uilabel(app.v101UIFigure);
            app.Label_4.Text = '组数';
            app.EditFieldPWzs = uieditfield(app.v101UIFigure, 'numeric');
            app.EditFieldPWzs.Limits = [1 128];
            app.EditFieldPWzs.Value = 1;
            % Show the figure after all components are created
            app.v101UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = genPasswordGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.v101UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.v101UIFigure)
        end
    end
end